classdef EEG < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ComputingTimePanel            matlab.ui.container.Panel
        CoumputeTime                  matlab.ui.control.EditField
        JenisEmosiPanel               matlab.ui.container.Panel
        Description                   matlab.ui.control.TextArea
        Diagnose                      matlab.ui.control.EditField
        ProsesdanPrediksiEmosiButton  matlab.ui.control.Button
        ResetButton                   matlab.ui.control.Button
        BrowseFile                    matlab.ui.control.EditField
        SelectDataEditFieldLabel      matlab.ui.control.Label
        BrowseButton                  matlab.ui.control.Button
        Judul                         matlab.ui.control.Label
        EEGSIGNALPanel                matlab.ui.container.Panel
        Graph                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [file, path] = uigetfile('*.csv', 'Pilih File EEG');
            if isequal(file,0)
                return;
            end
            fullpath = fullfile(path, file);
            app.BrowseFile.Value = fullpath;
        end

        % Button pushed function: ProsesdanPrediksiEmosiButton
        function ProsesdanPrediksiEmosiButtonPushed(app, event)
            tic; % mulai hitung waktu
            try
                % --- Pastikan file dipilih ---
                if isempty(app.BrowseFile.Value)
                    uialert(app.UIFigure, 'Silakan pilih file CSV terlebih dahulu!', 'File Tidak Ditemukan');
                    return;
                end
    
                % --- Muat model hasil training ---
                load('EEG_Model.mat', 'TrainedModel', 'Mu', 'Sigma', 'Fs', 'K_MAX_HFD');
            
                % --- Baca file EEG baru ---
                T = readtable(app.BrowseFile.Value);
                if ~ismember('eegRawValueVolts', T.Properties.VariableNames)
                    uialert(app.UIFigure, 'Kolom eegRawValueVolts tidak ditemukan!', 'Error');
                    return;
                end
            
                RawEEG = T.eegRawValueVolts;
                if ismember('poorSignal', T.Properties.VariableNames)
                    CleanIndices = find(T.poorSignal <= 20);
                    if isempty(CleanIndices)
                        CleanIndices = 1:length(RawEEG);
                    end
                    RawEEG = RawEEG(CleanIndices);
                end
            
                % --- Tampilkan sinyal EEG ---
                plot(app.Graph, RawEEG);
                xlabel(app.Graph, 'Sample');
                ylabel(app.Graph, 'Amplitude (Volt)');
                title(app.Graph, 'Sinyal EEG Baru');
    
                % --- Proses wavelet decomposition (db4) ---
                [c, l] = wavedec(RawEEG, 5, 'db4');
                D3 = wrcoef('d', c, l, 'db4', 3);
                D4 = wrcoef('d', c, l, 'db4', 4);
                Beta_Signal = D3 + D4;
            
                % --- Hitung fitur HFD ---
                HFD_Feature = HiguchiFD(Beta_Signal, K_MAX_HFD);
            
                % --- Normalisasi dengan parameter training ---
                X_norm = (HFD_Feature - Mu) / Sigma;
            
                % --- Prediksi emosi ---
                PredictedLabel = predict(TrainedModel, X_norm);
            
                % --- Tampilkan hasil ---
                app.Diagnose.Value = char(PredictedLabel);
            
                if strcmpi(PredictedLabel, 'Netral')
                    app.Description.Value = "Kondisi emosi stabil, tidak ada rangsangan positif atau negatif dominan.";
                elseif strcmpi(PredictedLabel, 'Rileks')
                    app.Description.Value = "Aktivitas beta tinggi — indikasi suasana hati positif dan bahagia.";
                else
                    app.Description.Value = "Kelas emosi tidak terdeteksi.";
                end
            
                % --- Waktu komputasi ---
                elapsed = toc;
                app.CoumputeTime.Value = sprintf('%.3f detik', elapsed);
            
            catch ME
                uialert(app.UIFigure, sprintf('Terjadi kesalahan:\n%s', ME.message), 'Error');
            end
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.BrowseFile.Value = '';
            app.Diagnose.Value = '';
            app.Description.Value = '';
            app.CoumputeTime.Value = '';
            cla(app.Graph);
            title(app.Graph, 'EEG SIGNAL');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EEGSIGNALPanel = uipanel(app.UIFigure);
            app.EEGSIGNALPanel.Title = 'EEG SIGNAL';
            app.Graph = uiaxes(app.EEGSIGNALPanel);
            xlabel(app.Graph, 'Time(s)')
            ylabel(app.Graph, 'Amplitude')
            zlabel(app.Graph, 'Z')
            app.Graph.YTickLabel = {'-0.5'; '0'; '0.5'};
            app.Judul = uilabel(app.UIFigure);
            app.Judul.Text = {'KLASIFIKASI JENIS EMOSI MENGGUNAKAN'; 'DIMENSI HIGUCHI DENGAN K-NEAREST NEIGHBOR'};
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SelectDataEditFieldLabel = uilabel(app.UIFigure);
            app.SelectDataEditFieldLabel.Text = 'Select Data';
            app.BrowseFile = uieditfield(app.UIFigure, 'text');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ProsesdanPrediksiEmosiButton = uibutton(app.UIFigure, 'push');
            app.ProsesdanPrediksiEmosiButton.ButtonPushedFcn = createCallbackFcn(app, @ProsesdanPrediksiEmosiButtonPushed, true);
            app.ProsesdanPrediksiEmosiButton.Text = {'Proses dan'; 'Prediksi Emosi'};
            app.JenisEmosiPanel = uipanel(app.UIFigure);
            app.JenisEmosiPanel.Title = 'Jenis Emosi';
            app.Diagnose = uieditfield(app.JenisEmosiPanel, 'text');
            app.Description = uitextarea(app.JenisEmosiPanel);
            app.ComputingTimePanel = uipanel(app.UIFigure);
            app.ComputingTimePanel.Title = 'Computing Time';
            app.CoumputeTime = uieditfield(app.ComputingTimePanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEG

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end